classdef CoreILP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        OptionsPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ShowforbiddenregionsCheckBox  matlab.ui.control.CheckBox
        RedrawButton                  matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        NumshopsgymsEditField         matlab.ui.control.NumericEditField
        NumshopsgymsEditFieldLabel    matlab.ui.control.Label
        AlwaysresetviewCheckBox       matlab.ui.control.CheckBox
        ShowteardropsCheckBox         matlab.ui.control.CheckBox
        ShowVoronoiCheckBox           matlab.ui.control.CheckBox
        ShowlinesButtonGroup          matlab.ui.container.ButtonGroup
        ShadowButton                  matlab.ui.control.RadioButton
        CorecoreButton                matlab.ui.control.RadioButton
        CoreforbiddenButton           matlab.ui.control.RadioButton
        SegmentclosenessButton        matlab.ui.control.RadioButton
        ShownolineButton              matlab.ui.control.RadioButton
        ControlsPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ShowpenaltiesButton           matlab.ui.control.StateButton
        ShadowlogicButton             matlab.ui.control.StateButton
        ThreeormorecoreintersectionhaltlogicButton  matlab.ui.control.StateButton
        CorecoremustlogicButton       matlab.ui.control.StateButton
        CoreforbiddenintersectionlogicButton  matlab.ui.control.StateButton
        SegmentclosenesslogicButton   matlab.ui.control.StateButton
        LoadasButton                  matlab.ui.control.Button
        QuickloadButton               matlab.ui.control.Button
        SaveasButton                  matlab.ui.control.Button
        QuicksaveButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        numShops % number of shops
        numGyms % number of gyms
        shops % matrix of N*2 positions
        gyms % matrix of N*2 positions
        shopsPOIs % array of ROIs (draggable points)
        gymsPOIs % array of ROIs (draggable points)
        isOnLine % are shops and gyms on one line (1D)
        %isChangedForSave % a boolean to check if there is a change
        isDrawForbiddens % boolean for drawing the forbidden regions
        isDrawCores % boolean for drawing cores
        isSegmentClosenessLogicActive % boolean to control segment closeness logic
        isCoreCoreMustActive % boolean to control core core intersection logic
        isCoreForbiddenActive % boolean to control core forbidden intersection logic
        isThreeCoreHaltActive % boolean to control core intersecting with 2 or more other cores logic
        isShadowActive % boolean to control shadow logic
        isShowPenalties % boolean to control shadow logic


        coreForbiddenMatrix % core forebidden matrix stored for visualization
        segmentClosenessForbiddenMatrix % segment closeness forbidden matrix stored for visualization
        coreCoreMustMatrix % core core must matrix stored for visualization
        shadowsForbiddensMatrix % forbiddens made by shadows
        lastSolution % last ILP solution used in the "drawMatrixLines" function because I need to delete all lines and solution lines need to be drawn again

        isShowVoronoi % show voronoi or not
        isShowTeardrop % show teardrop or not
        isAlwaysResetView % should the axis be rest by any dragging or changes?
    end

    methods (Access = public)

        function ROIEvent(app,src,evt)
            n=size(app.shopsPOIs,2);
            shopPositions(n,2)=0;
            for i=1:n
                shopPositions(i,:)=app.shopsPOIs{1,i}.Position;
            end
            n=size(app.gymsPOIs,2);
            gymPositions(n,2)=0;
            for i=1:n
                gymPositions(i,:)=app.gymsPOIs{1,i}.Position;
            end
            if app.isOnLine==true
                for i=1:size(shopPositions,1)
                    shopPositions(i,2)=rand(1,1)*0.001;
                end
                for i=1:size(gymPositions,1)
                    gymPositions(i,2)=rand(1,1)*0.001;
                end
            end
            %figure(1);
            evname = evt.EventName;
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end
            switch(evname)
                case{'MovingROI'}
                    app.shops=shopPositions;
                    app.gyms=gymPositions;
                    title(app.UIAxes,'RUNNING ...')
                    run(app,shopPositions,gymPositions);
                    title(app.UIAxes,'')
                case{'ROIMoved'}
                    app.shops=shopPositions;
                    app.gyms=gymPositions;
                    title(app.UIAxes,'RUNNING ...')
                    run(app,shopPositions,gymPositions);
                    title(app.UIAxes,'')
            end
            %app.isChangedForSave=true;
        end

        function run(app,shops,gyms)
            app.coreForbiddenMatrix=[];
            app.segmentClosenessForbiddenMatrix=[];
            app.coreCoreMustMatrix=[];
            app.lastSolution=[];
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            if app.isAlwaysResetView==true
                if app.isOnLine==true
                    ylim(app.UIAxes,[-0.5,0.5])
                    xlim(app.UIAxes,[-1,2])
                else
                    ylim(app.UIAxes,[0,1])
                    xlim(app.UIAxes,[0,1])
                end
            end
            daspect(app.UIAxes,[1,1,1])

            numShops=size(shops,1);
            numGyms=size(gyms,1);
            % \/\/\/ DRAW PENALTIES FIRST
            if app.isShowPenalties==true
                drawPenalties(app);
            end
            % ^^^ DRAW PENALTIES FIRST
            % \/\/\/ GET CORES AND REGIONS
            if app.isDrawCores==true
                [SVCells,GVCells]=calcCores(app,shops,gyms);%DONE
                drawCore(app,SVCells,GVCells,shops,gyms);%DONE
            end
            if app.isDrawForbiddens==true
                drawForbiddenAreas(app,shops,gyms);
            end
            % ^^^ GET CORES AND REGIONS

            mergedForbiddens(size(shops,1),size(gyms,1))=0;
            mergedMusts(size(shops,1),size(gyms,1))=0;

            if app.isOnLine==true
                % SAMPLE FROM SPACE
                points=linspace(-1,2,1000);
                [musts,forbiddens]=assignForbiddensMusts1D(app,points,shops,gyms);
                app.coreForbiddenMatrix=forbiddens';
                app.coreCoreMustMatrix=musts';
            else
                x=-2:0.05:2;
                y=-2:0.05:2;
                [X,Y] = meshgrid(x,y);
                points(2,size(x,2)*size(y,2))=0;
                counter=1;
                for i=1:size(X,1)
                    for j=1:size(X,2)
                        points(:,counter)=[X(i,j),Y(i,j)];
                        counter=counter+1;
                    end
                end
                [musts,forbiddens]=assignForbiddensMusts2D(app,points,shops,gyms);
                app.coreForbiddenMatrix=forbiddens';
                app.coreCoreMustMatrix=musts';
                if app.isShadowActive==true
                    forbiddensShadow=checkShadow(app);
                    app.shadowsForbiddensMatrix=forbiddensShadow;
                end
            end

            if app.isThreeCoreHaltActive==true
                if sum(musts,1)>=1
                    disp('A core has intersection with two or more cores')
                    return;
                end
                if sum(musts,2)>=1
                    disp('A core has intersection with two or more cores')
                    return;
                end
            end
            if app.isCoreForbiddenActive==true
                mergedForbiddens=mergedForbiddens+forbiddens;
                mergedForbiddens(mergedForbiddens>=1)=1;
            end
            if app.isCoreCoreMustActive==true
                mergedMusts=musts+mergedMusts;
                mergedMusts(mergedMusts>=1)=1;
            end
            if app.isShadowActive==true
                mergedForbiddens=mergedForbiddens+forbiddensShadow;
                mergedForbiddens(mergedForbiddens>=1)=1;
            end

            distances(size(shops,1),size(gyms,1))=0;
            for i=1:numShops
                for j=1:numGyms
                    %distances(i,j)=pdist([shops(i,:);gyms(j,:)]);
                    distances(i,j)=manualPdist(app,[shops(i,:);gyms(j,:)]);
                end
            end


            gymsshops=zeros(size(gyms,1),4);
            gymsshops(:,1:2)=gyms ;
            gymsshops(:,3:4)=shops ;
            %writematrix(gymsshops, strcat('gymsshops',strrep(char(datetime),':','_')));

            %Alon
            %datetime
            %writematrix(gyms, strcat('gyms',char(datetime)));
            %writematrix(shops, strcat('shops',char(datetime)));

            %Alon: Please add
            if app.isSegmentClosenessLogicActive==true
                fSegments=checkingAllSegments(gyms,shops);
                mergedForbiddens=fSegments'+mergedForbiddens;
                mergedForbiddens(mergedForbiddens>=1)=1;
                app.segmentClosenessForbiddenMatrix=fSegments;
            end

            [f,intcon,A,b,Aeq,beq,lb,ub]=createILP(app,mergedMusts,mergedForbiddens,distances);
            x = intlinprog(f,intcon,A,b,Aeq,beq,lb,ub)
            app.lastSolution=x;
            datetime
            if isempty(x)==0
                x=decodeSolution(app,x,numShops,numGyms,true);
                if app.isShowTeardrop==true
                    drawDroplets(app,x);
                end
                objValue=sum(sum(x.*distances))
            end

            for i=1:numShops
                app.shopsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',shops(i,:),'Color','b');
                addlistener(app.shopsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            for i=1:numGyms
                app.gymsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',gyms(i,:),'Color','g');
                addlistener(app.gymsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            drawMatrixLines(app);
            
            
            for i=1:numShops
                app.shopsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',shops(i,:),'Color','b');
                %addlistener(shopsPOIs{1,i},'MovingROI',@app.ROIEvent);
                addlistener(app.shopsPOIs{1,i},'ROIMoved',@app.ROIEvent);
                text(app.UIAxes,shops(i,1)+0.01,shops(i,2)+0.01,strcat('Shop',num2str(i)))
            end
            for i=1:numGyms
                app.gymsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',gyms(i,:),'Color','g');
                %addlistener(shopsPOIs{1,i},'MovingROI',@app.ROIEvent);
                addlistener(app.gymsPOIs{1,i},'ROIMoved',@app.ROIEvent);
                text(app.UIAxes,gyms(i,1)+0.01,gyms(i,2)+0.01,strcat('Gym',num2str(i)))
            end
            %disp('!!!')
        end

        function x = decodeSolution(app,x,numShops,numGyms,isReport)
            x=reshape(x,[numShops,numGyms])';
            if isReport==true
                for i=1:numShops
                    for j=1:numGyms
                        if x(i,j)==1
                            fprintf('Shop %i is connected to Gym %i\n', i,j)
                            h=line(app.UIAxes,[app.shops(i,1),app.gyms(j,1)],[app.shops(i,2),app.gyms(j,2)],'color','r','LineWidth',8,'LineStyle','-');
                            h.Color = [h.Color 0.5];
                        end
                    end
                end
            end
        end

        function [f,intcon,A,b,Aeq,beq,lb,ub]=createILP(app,musts,forbiddens,distances)
            f=reshape(distances',[size(musts,1)*size(musts,2),1]);
            intcon=1:size(musts,1)*size(musts,2);
            A(2*size(musts,1)*size(musts,2),size(musts,1)*size(musts,2))=0;
            b(2*size(musts,1)*size(musts,2),1)=0;
            counter=1;
            for i=1:size(musts,1)
                for j=1:size(musts,2)
                    col=((i-1)*size(musts,2))+j;
                    A(counter,col)=-1;
                    b(counter,1)=-musts(i,j);
                    counter=counter+1;
                end
            end
            for i=1:size(musts,1)
                for j=1:size(musts,2)
                    col=((i-1)*size(musts,2))+j;
                    A(counter,col)=1;
                    b(counter,1)=1-forbiddens(i,j);
                    counter=counter+1;
                end
            end
            counter=1;
            Aeq(size(musts,1)+size(musts,2),size(musts,1)*size(musts,2))=0;
            beq(size(musts,1)+size(musts,2),1)=0;
            for i=1:size(musts,1)
                for j=1:size(musts,2)
                    col=((i-1)*size(musts,2))+j;
                    Aeq(counter,col)=1;
                end
                beq(counter,1)=1;
                counter=counter+1;
            end
            for j=1:size(musts,2)
                for i=1:size(musts,1)
                    col=((i-1)*size(musts,2))+j;
                    Aeq(counter,col)=1;
                end
                beq(counter,1)=1;
                counter=counter+1;
            end
            lb(size(musts,1)*size(musts,2),1)=0;
            ub(size(musts,1)*size(musts,2),1)=0;
            ub(:,1)=1;
        end

        function [musts,forbiddens] = assignForbiddensMusts2D(app,points,shops,gyms)
            forbiddens(size(shops,1),size(gyms,1))=0;
            musts(size(shops,1),size(gyms,1))=0;
            for i=1:size(points,2)
                %points(1,i)=0.3;
                isInShopCores=isInCore(app,points(:,i),shops);
                isInGymCores=isInCore(app,points(:,i),gyms);
                isInShopForbiddens=isInForbidden(app,points(:,i),shops);
                isInGymForbiddens=isInForbidden(app,points(:,i),gyms);
                for m=1:size(shops,1)
                    for n=1:size(gyms,1)
                        if isInShopCores(1,m)==1 && isInGymCores(1,n)==1
                            musts(m,n)=1;
                        end
                    end
                end
                for m=1:size(shops,1)
                    for n=1:size(gyms,1)
                        if isInShopForbiddens(1,m)==1 && isInGymCores(1,n)==1
                            forbiddens(m,n)=1;
                            %if forbiddens(2,1)==1
                            %    disp('!!!')
                            %end
                        end
                    end
                end
                for n=1:size(shops,1)
                    for m=1:size(gyms,1)
                        if isInGymForbiddens(1,m)==1 && isInShopCores(1,n)==1
                            forbiddens(n,m)=1;
                            %                 if forbiddens(1,3)==1
                            %                     disp('!!!')
                            %                 end
                        end
                    end
                end
                %     scatter(points(1,i),0,100)

                %     if sum(sum(forbiddens))>5
                %         disp('!!!')
                %     end
                %disp('!!!')
            end
            %disp('!!!')
        end

        function [musts,forbiddens] = assignForbiddensMusts1D(app,points,shops,gyms)
            forbiddens(size(shops,1),size(gyms,1))=0;
            musts(size(shops,1),size(gyms,1))=0;
            for i=1:size(points,2)
                %points(1,i)=0.3;
                isInShopCores=isInCore([points(1,i);0],shops);
                isInGymCores=isInCore([points(1,i);0],gyms);
                isInShopForbiddens=isInForbidden([points(1,i);0],shops);
                isInGymForbiddens=isInForbidden([points(1,i);0],gyms);
                for m=1:size(shops,1)
                    for n=1:size(gyms,1)
                        if isInShopCores(1,m)==1 && isInGymCores(1,n)==1
                            musts(m,n)=1;
                        end
                    end
                end
                for m=1:size(shops,1)
                    for n=1:size(gyms,1)
                        if isInShopForbiddens(1,m)==1 && isInGymCores(1,n)==1
                            forbiddens(m,n)=1;
                            %                 if forbiddens(1,3)==1
                            %                     disp('!!!')
                            %                 end
                        end
                    end
                end
                for n=1:size(shops,1)
                    for m=1:size(gyms,1)
                        if isInGymForbiddens(1,m)==1 && isInShopCores(1,n)==1
                            forbiddens(n,m)=1;
                            %                 if forbiddens(1,3)==1
                            %                     disp('!!!')
                            %                 end
                        end
                    end
                end
                %     scatter(points(1,i),0,100)

                %     if sum(sum(forbiddens))>5
                %         disp('!!!')
                %     end
                %disp('!!!')
            end
        end

        function isInCores = isInCore(app,point,POIs)
            isInCores(1,size(POIs,1))=0;
            for i=1:size(POIs,1)
                isInCoreTemp=1;
                for j=1:size(POIs,1)
                    if i~=j
                        %if pdist([point';POIs(i,:)])>pdist([point';POIs(j,:)])/2
                        if manualPdist(app,[point';POIs(i,:)])>manualPdist(app,[point';POIs(j,:)])/2
                            isInCoreTemp=0;
                            break;
                        end
                    end
                end
                isInCores(1,i)=isInCoreTemp;
                if isInCoreTemp==1
                    %scatter(POIs(i,1),POIs(i,2),200);
                    %scatter(point(1,1),0,200,'filled');
                    %disp('!!!')
                end
            end
        end

        function isInForbiddens = isInForbidden(app,point,POIs)
            isInForbiddens(1,size(POIs,1))=0;
            for i=1:size(POIs,1)
                for j=1:size(POIs,1)
                    if i~=j
                        %if pdist([point';POIs(j,:)])<pdist([point';POIs(i,:)])/2
                        if manualPdist(app,[point';POIs(j,:)])<manualPdist(app,[point';POIs(i,:)])/2
                            isInForbiddens(1,i)=1;
                            break;
                        end
                    end
                end
                %if isInForbiddens(1,i)==1
                %scatter(POIs(i,1),POIs(i,2),200);
                %scatter(point(1,1),0,200,'filled');
                %disp('!!!')
                %end
            end
        end

        function [allRegions,allCircles,allIntersections] = drawForbiddenAreas(app,shops,gyms)
            if app.isShowVoronoi==1
                h=voronoi(app.UIAxes,shops(:,1),shops(:,2));
                set(h, 'Color', 'b')

                h=voronoi(app.UIAxes,gyms(:,1),gyms(:,2));
                set(h, 'Color', 'g')
            end

            %legend('Shop POI','Shop forbidden','Gym POI','Gym forbidden')

            dt = delaunayTriangulation(shops);
            [SSV,SSR] = voronoiDiagram(dt);
            dt = delaunayTriangulation(gyms);
            [GSV,GSR] = voronoiDiagram(dt);

            SVCells=calcNeighbors(app,SSV,SSR,shops,false);
            GVCells=calcNeighbors(app,GSV,GSR,gyms,false);

            [allRegions,allCircles,allIntersections]=getAllRegions(app,SVCells,GVCells,SSR,GSR);
        end

        function [allRegions,allCircles,allIntersections] = getAllRegions(app,SVCells,GVCells,SSR,GSR)
            allRegions=cell(1,4);
            allRegions{1,1}='Region name';
            allRegions{1,2}='Region owners';
            allRegions{1,3}='Region forbidden to owners';
            allRegions{1,4}='Arcs';
            allCircles=cell(1,6);
            allCircles{1,1}='Circle index';
            allCircles{1,2}='Circle owner';
            allCircles{1,3}='Circle forbidden to owner';
            allCircles{1,4}='Circle center';
            allCircles{1,5}='Circle radius';
            allCircles{1,6}='Is excluded';
            allIntersections=cell(1,7);
            allIntersections{1,1}='Circle 1 index';
            allIntersections{1,2}='Circle 2 index';
            allIntersections{1,3}='Point';
            allIntersections{1,4}='Is checked';
            allIntersections{1,5}='Angle to circle 1';
            allIntersections{1,6}='Angle to circle 2';
            allIntersections{1,7}='Angle to circle candidate';
            counter=1;
            for i=1:size(SVCells,1)-1
                for j=1:size(SVCells{i+1,2},2)
                    allCircles{counter+1,1}=counter;
                    allCircles{counter+1,2}=strcat('S',num2str(SVCells{i+1,2}(1,j)));
                    allCircles{counter+1,3}=strcat('S',num2str(i));
                    allCircles{counter+1,4}=SVCells{i+1,3}(:,j);
                    allCircles{counter+1,5}=SVCells{i+1,4}(:,j);
                    counter=counter+1;
                    viscircles(app.UIAxes,SVCells{i+1,3}',SVCells{i+1,4}','Color','b');
                end
            end
            for i=1:size(GVCells,1)-1
                for j=1:size(GVCells{i+1,2},2)
                    allCircles{counter+1,1}=counter;
                    allCircles{counter+1,2}=strcat('G',num2str(GVCells{i+1,2}(1,j)));
                    allCircles{counter+1,3}=strcat('G',num2str(i));
                    allCircles{counter+1,4}=GVCells{i+1,3}(:,j);
                    allCircles{counter+1,5}=GVCells{i+1,4}(:,j);
                    counter=counter+1;
                    viscircles(app.UIAxes,GVCells{i+1,3}',GVCells{i+1,4}','Color','g');
                end
            end
            allIntersections=getAllIntersections(app,allCircles,allIntersections);
            [allCircles,allIntersections,allRegions]=checkAllCircles(app,allCircles,allIntersections,allRegions);
            allRegions=checkAllIntersections(app,allCircles,allIntersections,allRegions);
            drawRegions(app,allCircles,allRegions);
        end

        function drawRegions(app,allCircles,allRegions)
            eachArcStep=0.1;
            for cir=2:size(allRegions,1)
                xs=[];
                ys=[];
                isNeedsDraw=false;
                for i=1:size(allRegions{cir,4},2)
                    NI=allRegions{cir,4}(1,i);
                    isNeedsDraw=false;

                    if isempty(NI)==false
                        xc = allCircles{NI+1,4}(1,1);
                        yc = allCircles{NI+1,4}(2,1);
                        r = allCircles{NI+1,5};

                        if size(allRegions{cir,4},1)>2 && size(allRegions{cir,4},2)>2% WHY CAN THE SECOND CONDITION HAPPEN?
                            if allRegions{cir,4}(5,i)==1
                                if allRegions{cir,4}(2,i)>allRegions{cir,4}(3,i)
                                    theta_p = (allRegions{cir,4}(2,i))*pi/180:eachArcStep:(360)*pi/180;
                                    theta_pp = (0)*pi/180:eachArcStep:(allRegions{cir,4}(3,i))*pi/180;
                                    theta = [theta_p,theta_pp];
                                else
                                    theta = (allRegions{cir,4}(2,i))*pi/180:eachArcStep:(allRegions{cir,4}(3,i))*pi/180;
                                end
                            else
                                if allRegions{cir,4}(2,i)<allRegions{cir,4}(3,i)
                                    theta_p = (allRegions{cir,4}(3,i))*pi/180:eachArcStep:(360)*pi/180;
                                    theta_pp = (0)*pi/180:eachArcStep:(allRegions{cir,4}(2,i))*pi/180;
                                    theta=[theta_p,theta_pp];
                                else
                                    theta = (allRegions{cir,4}(3,i))*pi/180:eachArcStep:(allRegions{cir,4}(2,i))*pi/180;
                                end
                            end

                        else
                            theta = (0)*pi/180:eachArcStep:(360)*pi/180;
                        end

                        if cir==5
                            %disp('!!!')
                        end

                        x = xc + r*cos(theta);
                        y = yc + r*sin(theta);
                        if size(allRegions{cir,4},1)>2 && size(allRegions{cir,4},2)>2
                            if allRegions{cir,4}(5,i)==1
                                xs=[xs,x];
                                ys=[ys,y];
                            else
                                xs=[xs,flip(x)];
                                ys=[ys,flip(y)];
                                %xs=[x,xs];
                                %ys=[y,ys];
                            end
                        else
                            xs=[xs,x];
                            ys=[ys,y];
                        end

                        isNeedsDraw=true;
                    end
                end
                if isNeedsDraw==true
                    P = polyshape(xs,ys);
                    color=[0.8,0.8,0.8];
                    plot(P,'FaceColor',color,'FaceAlpha',0.6)
                end
                %disp('!!!')
            end
        end

        function allIntersections = getAllIntersections(app,allCircles,allIntersections)
            counter=1;
            for i=1:size(allCircles,1)-1
                for j=i+1:size(allCircles,1)-1
                    [xa,yb]=circcirc(allCircles{i+1,4}(1,1),allCircles{i+1,4}(2,1),allCircles{i+1,5}(1,1),allCircles{j+1,4}(1,1),allCircles{j+1,4}(2,1),allCircles{j+1,5}(1,1));
                    if ~isnan(xa(1,1))
                        allIntersections{counter+1,1}=i;
                        allIntersections{counter+1,2}=j;
                        allIntersections{counter+1,3}=[xa(1,1);yb(1,1)];

                        u=[allCircles{i+1,5}(1,1),0];
                        v=[xa(1,1)-allCircles{i+1,4}(1,1),yb(1,1)-allCircles{i+1,4}(2,1)];
                        u = u/norm(u);
                        v = v/norm(v);
                        angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                        if yb(1,1)-allCircles{i+1,4}(2,1)<0
                            angle=360-abs(angle);
                        end

                        allIntersections{counter+1,5}=angle;

                        u=[allCircles{j+1,5}(1,1),0];
                        v=[xa(1,1)-allCircles{j+1,4}(1,1),yb(1,1)-allCircles{j+1,4}(2,1)];
                        u = u/norm(u);
                        v = v/norm(v);
                        angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                        if yb(1,1)-allCircles{j+1,4}(2,1)<0
                            angle=360-abs(angle);
                        end

                        allIntersections{counter+1,6}=angle;
                        counter=counter+1;
                        allIntersections{counter+1,1}=i;
                        allIntersections{counter+1,2}=j;
                        allIntersections{counter+1,3}=[xa(1,2);yb(1,2)];

                        u=[allCircles{i+1,5}(1,1),0];
                        v=[xa(1,2)-allCircles{i+1,4}(1,1),yb(1,2)-allCircles{i+1,4}(2,1)];
                        u = u/norm(u);
                        v = v/norm(v);
                        angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                        if yb(1,2)-allCircles{i+1,4}(2,1)<0
                            angle=360-abs(angle);
                        end

                        allIntersections{counter+1,5}=angle;

                        u=[allCircles{j+1,5}(1,1),0];
                        v=[xa(1,2)-allCircles{j+1,4}(1,1),yb(1,2)-allCircles{j+1,4}(2,1)];
                        u = u/norm(u);
                        v = v/norm(v);
                        angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                        if yb(1,2)-allCircles{j+1,4}(2,1)<0
                            angle=360-abs(angle);
                        end

                        allIntersections{counter+1,6}=angle;
                        %disp('!!!')
                        counter=counter+1;
                    end
                end
            end
        end

        function allRegions = checkAllIntersections(app,allCircles,allIntersections,allRegions)
            lastRowAllRegions=size(allRegions,1);
            candidateTextNumber=1;
            for i=1:size(allIntersections,1)-1
                generatedPoints=generateFourPoints(app,allCircles,allIntersections{i+1,3},allIntersections{i+1,1},allIntersections{i+1,2},0.05);
                %scatter(allIntersections{i+1,3}(1,1),allIntersections{i+1,3}(2,1))
                for j=1:4
                    %scatter(generatedPoints(1,j),generatedPoints(2,j))
                    %text(generatedPoints(1,j),generatedPoints(2,j),num2str(candidateTextNumber))
                    candidateTextNumber=candidateTextNumber+1;
                    forbiddens=getForbiddensOfPoint(app,generatedPoints(:,j),allCircles);
                    forbiddenCircleIndices=[];
                    if sum(forbiddens)~=0
                        forbiddenCircleIndices(1,sum(forbiddens))=0;
                        counter=1;
                        for k=1:size(forbiddens,2)
                            if forbiddens(1,k)==1
                                forbiddenCircleIndices(1,counter)=k;
                                counter=counter+1;
                            end
                        end
                    end
                    if j==1
                        debug=1;
                    else
                        debug=0;
                    end
                    if sum(forbiddens)>0
                        arcs=calculateRegionArc(app,generatedPoints(:,j),allCircles,allIntersections,forbiddens,debug);
                    else
                        arcs=[];
                    end
                    isUnique=true;
                    for n=2:size(arcs,2)
                        if isempty(allIntersections{arcs(4,n),4})==0
                            isUnique=false;
                        end
                    end
                    for m=1:size(allRegions,1)-1
                        if isequal(allRegions{m+1,4},arcs)
                            isUnique=false;
                            break;
                        end
                    end

                    if isUnique==true
                        allRegions{lastRowAllRegions+1,3}=forbiddenCircleIndices;
                        allRegions{lastRowAllRegions+1,1}=lastRowAllRegions;
                        allRegions{lastRowAllRegions+1,2}=allCircles{allIntersections{i+1,1}+1,2};
                        allRegions{lastRowAllRegions+1,4}=arcs;

                        %IF THIS REGION IS UNIQUE ***
                        lastRowAllRegions=lastRowAllRegions+1;
                    else
                        %disp('NOT UNIQUE')
                    end

                end
                allIntersections{i+1,4}=1;

                %disp('!!!')
            end
        end

        function arcs = calculateRegionArc(app,internalPoint,allCircles,allIntersection,forbiddens,debug)
            %scatter(internalPoint(1,1),internalPoint(2,1))
            horizontalXs=[];
            for i=1:size(allCircles,1)-1
                [hX,~]=linecirc(0,internalPoint(2,1),allCircles{i+1,4}(1,1),allCircles{i+1,4}(2,1),allCircles{i+1,5}(1,1));
                if ~isnan(hX(1,1))
                    for m=1:size(hX,2)
                        horizontalXs(1,size(horizontalXs,2)+1)=hX(1,m);
                        horizontalXs(2,size(horizontalXs,2))=i;%INDEX OF THE NEIGHBOR'S CIRCLE
                    end
                end
            end
            dists=horizontalXs(1,:)-internalPoint(1,1);
            dists(dists<0)=inf;
            [~,I]=min(dists);
            closestInitialPoint=[horizontalXs(1,I),internalPoint(2,1)];

            II=horizontalXs(2,I);

            if debug==1
                %disp('!!!')
            end

            u=[allCircles{II+1,5}(1,1),0];
            v=[closestInitialPoint(1,1)-allCircles{II+1,4}(1,1),closestInitialPoint(1,2)-allCircles{II+1,4}(2,1)];
            u = u/norm(u);
            v = v/norm(v);
            initalAngle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
            if closestInitialPoint(1,2)-allCircles{II+1,4}(2,1)<0
                initalAngle=360-abs(initalAngle);
            end

            activeArcNI=horizontalXs(2,I);
            startNeighborArc=activeArcNI;
            currentActiveArc=startNeighborArc;
            arcs=startNeighborArc;
            currentAngle=0;

            arcs(2,size(arcs,2))=initalAngle;


            for i=1:size(allIntersection,1)-1
                u=[1,0];
                v=[allIntersection{i+1,3}(1,1)-internalPoint(1,1),allIntersection{i+1,3}(2,1)-internalPoint(2,1)];
                u = u/norm(u);
                v = v/norm(v);
                angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                if allIntersection{i+1,3}(2,1)-internalPoint(2,1)<0
                    angle=360-abs(angle);
                end
                allIntersection{i+1,7}=angle;
            end

            isNewArcFound=true;

            debugCounter=0;
            while(isNewArcFound==true)
                isNewArcFound=false;
                minAngle=inf;
                minAngleNotForbidden=inf;

                transitionArc=-1;
                transitionAngle=-1;
                transitionIntersectionIndex=-1;

                debugCounter=debugCounter+1;

                if debugCounter==4 && debug==1
                    %disp('!!!!!!')
                end

                for g=1:size(allIntersection,1)-1
                    if forbiddens(1,currentActiveArc)==1
                        if allIntersection{g+1,1}(1,1)==currentActiveArc
                            %if currentAngle<allIntersection{g+1,7}
                            %if minAngle>allIntersection{g+1,7}
                            finalAngle=-1;

                            teta_prime=arcs(2,size(arcs,2));
                            teta=allIntersection{g+1,5};
                            if teta_prime>0 && teta_prime<180 && teta>0 && teta<180
                                if teta_prime<=teta
                                    finalAngle=teta-teta_prime;
                                else
                                    finalAngle=360-(teta_prime-teta);
                                end
                            end
                            if teta_prime>180 && teta_prime<360 && teta>0 && teta<180
                                finalAngle=teta+360-teta_prime;
                            end
                            if teta_prime>0 && teta_prime<180 && teta>180 && teta<360
                                finalAngle=-teta_prime+teta;
                            end
                            if teta_prime>180 && teta_prime<360 && teta>180 && teta<360
                                if teta_prime<=teta
                                    finalAngle=teta-teta_prime;
                                else
                                    finalAngle=360-(teta_prime-teta);
                                end
                            end

                            %                 disp('From')
                            %                 allIntersection{g+1,1}(1,1)
                            %                 disp('To')
                            %                 allIntersection{g+1,2}(1,1)
                            %                 finalAngle
                            %                 disp('******')

                            if debugCounter==4 && debug==1
                                %                     disp('!!!!!!')
                            end
                            if finalAngle<minAngle && finalAngle~=0% && allIntersection{g+1,7}>globalAngle
                                %candidateGlobalAngle=allIntersection{g+1,7};
                                minAngle=finalAngle;
                                transitionArc=allIntersection{g+1,2};
                                if forbiddens(1,transitionArc)==0
                                    %minAngleNotForbidden=allIntersection{g+1,6};
                                    %minAngle=inf;
                                else
                                    %minAngleNotForbidden=inf;
                                    %minAngle=allIntersection{g+1,6};
                                end
                                transitionAngle=allIntersection{g+1,6};
                                transitionIntersectionIndex=g+1;
                                arcs(3,size(arcs,2))=allIntersection{g+1,5};
                                arcs(5,size(arcs,2))=1;
                                isNewArcFound=true;
                            end
                            %end
                            %end
                        elseif allIntersection{g+1,2}==currentActiveArc
                            %if currentAngle<allIntersection{g+1,7}
                            %if minAngle>allIntersection{g+1,7}
                            finalAngle=-1;

                            teta_prime=arcs(2,size(arcs,2));
                            teta=allIntersection{g+1,6};
                            if teta_prime>0 && teta_prime<180 && teta>0 && teta<180
                                if teta_prime<=teta
                                    finalAngle=teta-teta_prime;
                                else
                                    finalAngle=360-(teta_prime-teta);
                                end
                            end
                            if teta_prime>180 && teta_prime<360 && teta>0 && teta<180
                                finalAngle=(360-teta_prime)+teta;
                            end
                            if teta_prime>0 && teta_prime<180 && teta>180 && teta<360
                                finalAngle=-teta_prime+teta;
                            end
                            if teta_prime>180 && teta_prime<360 && teta>180 && teta<360
                                if teta_prime<=teta
                                    finalAngle=teta-teta_prime;
                                else
                                    finalAngle=360-(teta_prime-teta);
                                end
                            end

                            %                 disp('From')
                            %                 allIntersection{g+1,2}(1,1)
                            %                 disp('To')
                            %                 allIntersection{g+1,1}(1,1)
                            %                 finalAngle
                            %                 disp('******')

                            if debugCounter==4 && debug==1
                                %                     disp('!!!!!!')
                            end
                            if finalAngle<minAngle && finalAngle~=0% && allIntersection{g+1,7}>globalAngle
                                %candidateGlobalAngle=allIntersection{g+1,7};
                                minAngle=finalAngle;
                                transitionArc=allIntersection{g+1,1};
                                if forbiddens(1,transitionArc)==0
                                    %minAngleNotForbidden=allIntersection{g+1,5};
                                    %minAngle=inf;
                                else
                                    %minAngleNotForbidden=inf;
                                    %minAngle=allIntersection{g+1,5};
                                end
                                transitionAngle=allIntersection{g+1,5};
                                transitionIntersectionIndex=g+1;
                                arcs(3,size(arcs,2))=allIntersection{g+1,6};
                                arcs(5,size(arcs,2))=1;
                                isNewArcFound=true;
                            end
                            %end
                            %end
                        end
                    else
                        if allIntersection{g+1,1}(1,1)==currentActiveArc
                            %if currentAngle<allIntersection{g+1,7}
                            %if minAngle>allIntersection{g+1,7}% && allIntersection{g+1,5}<minAngleNotForbidden
                            finalAngle=-1;
                            teta_prime=arcs(2,size(arcs,2));
                            teta=allIntersection{g+1,5};
                            if teta_prime>0 && teta_prime<180 && teta>0 && teta<180
                                if teta_prime>=teta
                                    finalAngle=teta_prime-teta;
                                else
                                    finalAngle=360-(teta-teta_prime);
                                end
                            end
                            if teta_prime>180 && teta_prime<360 && teta>0 && teta<180
                                finalAngle=teta_prime-teta;
                            end
                            if teta_prime>0 && teta_prime<180 && teta>180 && teta<360
                                finalAngle=teta_prime+360-teta;
                            end
                            if teta_prime>180 && teta_prime<360 && teta>180 && teta<360
                                if teta_prime>=teta
                                    finalAngle=teta_prime-teta;
                                else
                                    finalAngle=360-(teta-teta_prime);
                                end
                            end
                            %                 disp('From')
                            %                 allIntersection{g+1,1}(1,1)
                            %                 disp('To')
                            %                 allIntersection{g+1,2}(1,1)
                            %                 finalAngle
                            %                 disp('******')

                            if debugCounter==4 && debug==1
                                %                     disp('!!!!!!')
                            end

                            %angleLeftSide=min(mod(abs(minAngleNotForbidden-allIntersection{g+1,5}),360),mod(abs(360-minAngleNotForbidden+allIntersection{g+1,5}),360));
                            if finalAngle<minAngleNotForbidden  && finalAngle~=0% && allIntersection{g+1,7}>globalAngle
                                %candidateGlobalAngle=allIntersection{g+1,7};
                                minAngleNotForbidden=finalAngle;
                                %minAngle=allIntersection{g+1,7};
                                transitionArc=allIntersection{g+1,2};
                                if forbiddens(1,transitionArc)==0
                                    %minAngleNotForbidden=allIntersection{g+1,6};
                                    %minAngle=inf;
                                else
                                    %minAngleNotForbidden=inf;
                                    %minAngle=allIntersection{g+1,6};
                                end
                                transitionAngle=allIntersection{g+1,6};
                                transitionIntersectionIndex=g+1;
                                arcs(3,size(arcs,2))=allIntersection{g+1,5};
                                arcs(5,size(arcs,2))=0;
                                isNewArcFound=true;
                            end
                            %end
                            %end
                        elseif allIntersection{g+1,2}==currentActiveArc
                            %if currentAngle<allIntersection{g+1,7}
                            %if minAngle>allIntersection{g+1,7}% && allIntersection{g+1,6}<minAngleNotForbidden
                            finalAngle=-1;
                            teta_prime=arcs(2,size(arcs,2));
                            teta=allIntersection{g+1,6};
                            if teta_prime>0 && teta_prime<180 && teta>0 && teta<180
                                if teta_prime>=teta
                                    finalAngle=teta_prime-teta;
                                else
                                    finalAngle=360-(teta-teta_prime);
                                end
                            end
                            if teta_prime>180 && teta_prime<360 && teta>0 && teta<180
                                finalAngle=teta_prime-teta;
                            end
                            if teta_prime>0 && teta_prime<180 && teta>180 && teta<360
                                finalAngle=teta_prime+360-teta;
                            end
                            if teta_prime>180 && teta_prime<360 && teta>180 && teta<360
                                if teta_prime>=teta
                                    finalAngle=teta_prime-teta;
                                else
                                    finalAngle=360-(teta-teta_prime);
                                end
                            end
                            %                 disp('From')
                            %                 allIntersection{g+1,2}(1,1)
                            %                 disp('To')
                            %                 allIntersection{g+1,1}(1,1)
                            %                 finalAngle
                            %                 disp('******')

                            if debugCounter==4 && debug==1
                                %                     disp('!!!!!!')
                            end

                            %angleLeftSide=min(mod(abs(minAngleNotForbidden-allIntersection{g+1,6}),360),mod(abs(360-minAngleNotForbidden+allIntersection{g+1,6}),360));
                            if finalAngle<minAngleNotForbidden  && finalAngle~=0% && allIntersection{g+1,7}>globalAngle
                                %candidateGlobalAngle=allIntersection{g+1,7};
                                minAngleNotForbidden=finalAngle;
                                %minAngle=allIntersection{g+1,7};
                                transitionArc=allIntersection{g+1,1};
                                if forbiddens(1,transitionArc)==0
                                    %minAngleNotForbidden=allIntersection{g+1,5};
                                    %minAngle=inf;
                                else
                                    %minAngleNotForbidden=inf;
                                    %minAngle=allIntersection{g+1,5};
                                end
                                transitionAngle=allIntersection{g+1,5};
                                transitionIntersectionIndex=g+1;
                                arcs(3,size(arcs,2))=allIntersection{g+1,6};
                                arcs(5,size(arcs,2))=0;
                                isNewArcFound=true;
                            end
                            %end
                            %end
                        end
                    end
                end
                if debug==1
                    %         disp('!!!')
                end
                if transitionArc>-1
                    if arcs(3,size(arcs,2))==arcs(3,1) && size(arcs,2)>1
                        break;%FOUND BEGINNING!
                    end
                    currentActiveArc=transitionArc;
                    currentAngle=minAngle;
                    %globalAngle=candidateGlobalAngle;
                    arcs(1,size(arcs,2)+1)=currentActiveArc;
                    arcs(2,size(arcs,2))=transitionAngle;
                    arcs(4,size(arcs,2))=transitionIntersectionIndex;
                    if forbiddens(1,transitionArc)==1
                        minAngleNotForbidden=inf;
                    else
                        minAngle=inf;
                    end
                end
            end
            arcs(3,size(arcs,2))=arcs(2,1);
            % if arcs(5,size(arcs,2))==1
            %     arcs(3,size(arcs,2))=arcs(2,1);
            % else
            %     arcs(3,size(arcs,2))=arcs(2,1);
            % end
        end

        function output = generateFourPoints(app,allCircles,intersectionPoint,circle1I,circle2I,segmentLength)
            circle1Center=allCircles{circle1I+1,4};
            tangent1M=((intersectionPoint(2,1)-circle1Center(2,1)))/((intersectionPoint(1,1)-circle1Center(1,1)));

            % dXdY=[1;tangent1M];
            % length=(dXdY(1,1)^2)+(dXdY(2,1)^2);
            % dXdY=dXdY/length;
            % dXdY=dXdY/100;
            % point1=intersectionPoint+dXdY;
            % point2=intersectionPoint-dXdY;
            % line([point1(1,1),point2(1,1)],[point1(2,1),point2(2,1)])

            circle1Center=allCircles{circle2I+1,4};
            tangent2M=((intersectionPoint(2,1)-circle1Center(2,1)))/((intersectionPoint(1,1)-circle1Center(1,1)));

            % dXdY=[1;tangent2M];
            % length=(dXdY(1,1)^2)+(dXdY(2,1)^2);
            % dXdY=dXdY/length;
            % dXdY=dXdY/100;
            % point1=intersectionPoint+dXdY;
            % point2=intersectionPoint-dXdY;
            % line([point1(1,1),point2(1,1)],[point1(2,1),point2(2,1)])

            tangent12M=(tangent2M+tangent1M)/2;

            if tangent12M>10
                %     disp('!!!')
            end

            dXdY=[1;tangent12M];
            length=(dXdY(1,1)^2)+(dXdY(2,1)^2);
            dXdY=dXdY/sqrt(length);
            dXdY=dXdY*segmentLength;
            point1=intersectionPoint+dXdY;
            point2=intersectionPoint-dXdY;
            %line([point1(1,1),point2(1,1)],[point1(2,1),point2(2,1)])

            tangent12M=(-1)/tangent12M;
            dXdY=[1;tangent12M];
            length=(dXdY(1,1)^2)+(dXdY(2,1)^2);
            dXdY=dXdY/sqrt(length);
            dXdY=dXdY*segmentLength;
            point3=intersectionPoint+dXdY;
            point4=intersectionPoint-dXdY;
            %line([point3(1,1),point4(1,1)],[point3(2,1),point4(2,1)])
            output=[point1,point2,point3,point4];
        end

        function [allCircles,allIntersections,allRegions] = checkAllCircles(app,allCircles,allIntersections,allRegions)
            noIntersectingCircles=1:size(allCircles,1)-1;
            noIntersectingCircles(2,size(noIntersectingCircles,2))=0;
            for i=1:size(allIntersections,1)-1
                noIntersectingCircles(2,allIntersections{i+1,1}(1,1))=1;
                noIntersectingCircles(2,allIntersections{i+1,2}(1,1))=1;
            end
            lastRowAllRegions=size(allRegions,1);
            for i=1:size(noIntersectingCircles,2)
                if noIntersectingCircles(2,i)==0
                    allCircles{i+1,6}=1;
                    allRegions{lastRowAllRegions+1,1}=lastRowAllRegions;
                    allRegions{lastRowAllRegions+1,2}=allCircles{i+1,2};
                    forbiddens=getForbiddensOfPoint(app,allCircles{i+1,4}(:,1),allCircles);
                    forbiddenCircleIndices=[];
                    forbiddenCircleIndices(1,sum(forbiddens))=0;
                    counter=1;
                    for j=1:size(forbiddens,2)
                        if forbiddens(1,j)==1
                            forbiddenCircleIndices(1,counter)=j;
                            counter=counter+1;
                        end
                    end
                    allRegions{lastRowAllRegions+1,3}=forbiddenCircleIndices;
                    allRegions{lastRowAllRegions+1,4}=i;
                    lastRowAllRegions=size(allRegions,1);
                    %disp('!!!')
                end
            end
        end

        function forbiddens = getForbiddensOfPoint(app,point,allCircles)
            forbiddens(1,size(allCircles,1)-1)=0;
            for i=1:size(allCircles,1)-1
                CX=allCircles{i+1,4}(1,1);
                CY=allCircles{i+1,4}(2,1);
                R=allCircles{i+1,5}(1,1);
                if (point(1,1)-CX)^2+(point(2,1)-CY)^2<R^2
                    forbiddens(1,i)=1;
                end
            end
        end

        function [SVCells,GVCells] = calcCores(app,shops,gyms)
            dt = delaunayTriangulation(shops);
            [SSV,SSR] = voronoiDiagram(dt);
            dt = delaunayTriangulation(gyms);
            [GSV,GSR] = voronoiDiagram(dt);

            SVCells=calcNeighbors(app,SSV,SSR,shops,true);
            GVCells=calcNeighbors(app,GSV,GSR,gyms,true);

            SVCells=calcArcs(app,SVCells,shops);
            GVCells=calcArcs(app,GVCells,gyms);
        end

        function VCells = calcArcs(app,VCells,locations)
            VCells{1,5}='Arcs';
            for i=1:size(VCells,1)-1
                %for j=1:size(VCells{i+1,2},2)
                %    fimplicit(@(x,y)sqrt((x-locations(VCells{i+1,2}(1,j),1))^2+(y-locations(VCells{i+1,2}(1,j),2))^2)/sqrt(((x-locations(i,1))^2+(y-locations(i,2))^2))-4)
                %end
                allIntersection=[];
                %intersectionDistances=[];
                intersections=[];
                horizontalXs=[];
                for j=1:size(VCells{i+1,2},2)
                    for k=j+1:size(VCells{i+1,2},2)
                        if j~=k
                            %neigh1L=locations(VCells{i+1,2}(1,j),:);
                            %neigh2L=locations(VCells{i+1,2}(1,k),:);
                            %neigh1LX=neigh1L(1,1);
                            %neigh1LY=neigh1L(1,2);
                            %neigh2LX=neigh2L(1,1);
                            %neigh2LY=neigh2L(1,2);

                            %cLX=locations(i,1);
                            %cLY=locations(i,2);

                            %syms x y
                            %S=solve(sqrt((x-neigh1LX)^2+(y-neigh1LY)^2)==sqrt((x-neigh2LX)^2+(y-neigh2LY)^2),y)
                            %lineEqn=2*x*(neigh1LX-neigh2LX)+2*y*(neigh1LY-neigh2LY)+((neigh2LX^2)-(neigh1LX^2)+(neigh2LY^2)-(neigh1LY^2));
                            %circleEqn=sqrt((x-neigh1LX)^2+(y-neigh1LY)^2)/sqrt(((x-cLX)^2+(y-cLY)^2));
                            %circleEqnO=sqrt((x-neigh2LX)^2+(y-neigh2LY)^2)/sqrt(((x-cLX)^2+(y-cLY)^2));

                            %fimplicit(y==S)
                            %fimplicit(lineEqn)
                            %if i==1
                            %viscircles(VCells{i+1,3}',VCells{i+1,4}')
                            %end
                            %fimplicit(circleEqn==2)%OLD IMPLICIT WAY. NOW I HAVE CIRCLES
                            %fimplicit(circleEqnO==4)

                            %eqn=sqrt((x-locations(VCells{i+1,2}(1,j),1))^2+(y-locations(VCells{i+1,2}(1,j),2))^2)==sqrt((x-locations(VCells{i+1,2}(1,k),1))^2+(y-locations(VCells{i+1,2}(1,k),2))^2);
                            %\/\/\/ OLD IMPLICIT SOLVE!
                            %S=solve([lineEqn==0,circleEqn==2],[x,y]);
                            %for h=1:size(S.x,1)
                            %    if isreal(S.x(h,1))==true
                            %        allIntersection(size(allIntersection,1)+1,1)=eval(S.x(h,1));
                            %        allIntersection(size(allIntersection,1),2)=eval(S.y(h,1));
                            %        intersectionDistances(size(intersectionDistances,1)+1,1)=pdist([locations(i,:);allIntersection(size(allIntersection,1),:)]);
                            %    end
                            %end
                            %^^^ OLD IMPLICIT SOLVE!
                            [xa,yb]=circcirc(VCells{i+1,3}(1,j),VCells{i+1,3}(2,j),VCells{i+1,4}(1,j),VCells{i+1,3}(1,k),VCells{i+1,3}(2,k),VCells{i+1,4}(1,k));
                            if ~isnan(xa(1,1))
                                for h=1:size(xa,2)
                                    allIntersection(size(allIntersection,1)+1,1)=xa(1,h);
                                    allIntersection(size(allIntersection,1),2)=yb(1,h);
                                    allIntersection(size(allIntersection,1),3)=VCells{i+1,2}(1,j);
                                    allIntersection(size(allIntersection,1),4)=VCells{i+1,2}(1,k);
                                    allIntersection(size(allIntersection,1),5)=i;

                                    %scatter(xa(1,h),yb(1,h));
                                    %scatter(VCells{i+1,3}(1,j),VCells{i+1,3}(2,j));
                                    %text(VCells{i+1,3}(1,j),VCells{i+1,3}(2,j),num2str(j))

                                    u=[VCells{i+1,4}(1,j),0];
                                    v=[xa(1,h)-VCells{i+1,3}(1,j),yb(1,h)-VCells{i+1,3}(2,j)];
                                    u = u/norm(u);
                                    v = v/norm(v);

                                    %angle = atan2(detV,dotV)*(180/pi);
                                    %aa=mod(atan2d(u,v)+360,360)
                                    angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                                    if yb(1,h)-VCells{i+1,3}(2,j)<0
                                        angle=360-abs(angle);
                                    end

                                    allIntersection(size(allIntersection,1),6)=angle;

                                    %scatter(VCells{i+1,3}(1,k),VCells{i+1,3}(2,k));
                                    %text(VCells{i+1,3}(1,k),VCells{i+1,3}(2,k),num2str(k))

                                    u=[VCells{i+1,4}(1,k),0];
                                    v=[xa(1,h)-VCells{i+1,3}(1,k),yb(1,h)-VCells{i+1,3}(2,k)];
                                    u = u/norm(u);
                                    v = v/norm(v);

                                    angle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                                    if yb(1,h)-VCells{i+1,3}(2,k)<0
                                        angle=360-abs(angle);
                                    end

                                    allIntersection(size(allIntersection,1),7)=angle;
                                    u=[VCells{i+1,4}(1,j),0];
                                    v=[xa(1,h)-locations(i,1),yb(1,h)-locations(i,2)];
                                    u = u/norm(u);
                                    v = v/norm(v);
                                    angleFromCenter=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                                    if yb(1,h)-locations(i,2)<0
                                        angleFromCenter=360-abs(angleFromCenter);
                                    end
                                    allIntersection(size(allIntersection,1),8)=angleFromCenter;
                                    %intersectionDistances(size(intersectionDistances,1)+1,1)=pdist([locations(i,:);allIntersection(size(allIntersection,1),[1,2])]);
                                end
                            end
                            %disp('!')
                        end
                    end
                    [hX,~]=linecirc(0,locations(i,2),VCells{i+1,3}(1,j),VCells{i+1,3}(2,j),VCells{i+1,4}(1,j));
                    for m=1:size(hX,2)
                        horizontalXs(1,size(horizontalXs,2)+1)=hX(1,m);
                        horizontalXs(2,size(horizontalXs,2))=j;%INDEX OF THE NEIGHBOR'S CIRCLE
                    end
                end
                %polyout = intersect(intersections);
                %activeArcNI=0;
                dists=horizontalXs(1,:)-locations(i,1);
                dists(dists<0)=inf;
                [~,I]=min(dists);
                closestInitialPoint=[horizontalXs(1,I),locations(i,2)];

                II=horizontalXs(2,I);

                u=[VCells{i+1,4}(1,II),0];
                v=[closestInitialPoint(1,1)-VCells{i+1,3}(1,II),closestInitialPoint(1,2)-VCells{i+1,3}(2,II)];
                u = u/norm(u);
                v = v/norm(v);
                initalAngle=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;
                if closestInitialPoint(1,2)-VCells{i+1,3}(2,II)<0
                    initalAngle=360-abs(initalAngle);
                end

                activeArcNI=horizontalXs(2,I);
                startNeighborArc=VCells{i+1,2}(1,activeArcNI);
                currentActiveArc=startNeighborArc;
                arcs=startNeighborArc;
                currentAngle=0;

                arcs(2,size(arcs,2))=initalAngle;
                isNewArcFound=true;
                while(isNewArcFound==true)
                    isNewArcFound=false;
                    minAngle=inf;
                    transitionArc=-1;
                    transitionAngle=-1;
                    for g=1:size(allIntersection,1)
                        if allIntersection(g,3)==currentActiveArc
                            if currentAngle<allIntersection(g,8)
                                if minAngle>allIntersection(g,8)
                                    minAngle=allIntersection(g,8);
                                    transitionArc=allIntersection(g,4);
                                    transitionAngle=allIntersection(g,7);
                                    arcs(3,size(arcs,2))=allIntersection(g,6);
                                    isNewArcFound=true;
                                end
                            end
                        elseif allIntersection(g,4)==currentActiveArc
                            if currentAngle<allIntersection(g,8)
                                if minAngle>allIntersection(g,8)
                                    minAngle=allIntersection(g,8);
                                    transitionArc=allIntersection(g,3);
                                    transitionAngle=allIntersection(g,6);
                                    arcs(3,size(arcs,2))=allIntersection(g,7);
                                    isNewArcFound=true;
                                end
                            end
                        end
                    end
                    if transitionArc>-1
                        currentActiveArc=transitionArc;
                        currentAngle=minAngle;
                        arcs(1,size(arcs,2)+1)=currentActiveArc;
                        arcs(2,size(arcs,2))=transitionAngle;
                    end
                end
                arcs(size(arcs,1),size(arcs,2))=arcs(2,1);
                VCells{i+1,5}=arcs;
                %disp('!')
            end
        end

        function VCells = calcNeighbors(app,SV,SR,locations,isSelf)
            VCells=cell(1,5);
            VCells{1,1}='VertexIndices';
            VCells{1,2}='Cell neighbors';
            VCells{1,3}='Cell neighbors circle centers';
            VCells{1,4}='Cell neighbors circle radiuses';
            for i=1:size(SR,1)
                VCells{i+1,1}=SR{i,1};
            end

            for i=1:size(SR,1)
                neighbors=[];
                for j=1:size(SR{i,1},2)
                    t=SR{i,1}(1,j);
                    for k=1:size(SR,1)
                        for m=1:size(SR{k,1},2)
                            if SR{k,1}(1,m)==t
                                if k~=i && t~=1
                                    neighbors(1,size(neighbors,2)+1)=k;
                                end
                            end
                        end
                    end
                end
                VCells{i+1,2}=unique(neighbors);
            end
            %for i=1:size(SR,1)
            %    text(locations(i,1),locations(i,2),num2str(i))
            %end
            %\/\/\/ DETECT NEIGHBOR CIRCLES' CENTERS AND RADIUSES
            for i=1:size(SR,1)
                centers=zeros(2,size(VCells{i+1,2},2));
                radiuses=zeros(1,size(VCells{i+1,2},2));
                for j=1:size(VCells{i+1,2},2)
                    centerSelf=locations(i,:);
                    centerNeighbor=locations(VCells{i+1,2}(1,j),:);
                    centerVector=-centerSelf+centerNeighbor;
                    if isSelf==true
                        centers(:,j)=(centerSelf-(1/3)*centerVector)';
                    else
                        centers(:,j)=(centerNeighbor+(1/3)*centerVector)';
                    end
                    radiuses(1,j)=sqrt((centerVector(1,1)^2)+(centerVector(1,2)^2))*(2/3);
                end
                VCells{i+1,3}=centers;
                VCells{i+1,4}=radiuses;
            end
            %^^^ DETECT NEIGHBOR CIRCLES' CENTERS AND RADIUSES
            %disp('!')
        end


        function drawCore(app,ShopVCells,gymVCells,shops,gyms)
            drawVCells(app,ShopVCells,'b',shops)
            drawVCells(app,gymVCells,'g',gyms)

            %disp('!!!')
        end

        function drawVCells(app,VCells,color,POIs)
            if app.isShowVoronoi==1
                h=voronoi(app.UIAxes,POIs(:,1),POIs(:,2));
                set(h, 'Color', color)
            end
            eachArcStep=0.01;
            for cir=2:size(VCells,1)
                xs=[];
                ys=[];
                isNeedsDraw=false;
                for i=1:size(VCells{cir,5},2)
                    NI=[];
                    isNeedsDraw=false;
                    for j=1:size(VCells{cir,2},2)
                        if VCells{cir,5}(1,i)==VCells{cir,2}(1,j)
                            NI = j;
                            break;
                        end
                    end
                    if isempty(NI)==false
                        xc = VCells{cir,3}(1,NI);
                        yc = VCells{cir,3}(2,NI);
                        r = VCells{cir,4}(1,NI);

                        if size(VCells{cir,5},1)>2
                            if VCells{cir,5}(2,i)>VCells{cir,5}(3,i)
                                theta_p = (VCells{cir,5}(2,i))*pi/180:eachArcStep:(360)*pi/180;
                                theta_pp = (0)*pi/180:eachArcStep:(VCells{cir,5}(3,i))*pi/180;
                                theta=[theta_p,theta_pp];
                            else
                                theta = (VCells{cir,5}(2,i))*pi/180:eachArcStep:(VCells{cir,5}(3,i))*pi/180;
                            end
                        else
                            theta = (0)*pi/180:eachArcStep:(360)*pi/180;
                        end

                        x = xc + r*cos(theta);
                        y = yc + r*sin(theta);
                        xs=[xs,x];
                        ys=[ys,y];
                        isNeedsDraw=true;
                    end
                end
                if isNeedsDraw==true
                    P = polyshape(xs,ys);
                    plot(app.UIAxes,P,'FaceColor',color,'FaceAlpha',0.1)
                end
                %disp('!!!')
            end
        end

        function dist = manualPdist(app,points)
            dist=sqrt((points(1,1)-points(2,1))^2+(points(1,2)-points(2,2))^2);
        end

        function drawMatrixLines(app)
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                for i=1:size(axesHandlesToChildObjects,1)
                    if(isvalid(axesHandlesToChildObjects(i,1))==1)
                        if strcmp(get(axesHandlesToChildObjects(i,1),'UserData'),'forbiddenMatrix')==1
                            delete(axesHandlesToChildObjects(i,1));
                        end
                    end
                end
%                 delete(axesHandlesToChildObjects);
            end
            if app.isShowVoronoi==1
                h=voronoi(app.UIAxes,app.shops(:,1),app.shops(:,2));
                set(h, 'Color', 'b')

                h=voronoi(app.UIAxes,app.gyms(:,1),app.gyms(:,2));
                set(h, 'Color', 'g')
            end
            if isempty(app.lastSolution)==0
                x=decodeSolution(app,app.lastSolution,app.numShops,app.numGyms,true);
            end
            selectedButton = app.ShowlinesButtonGroup.SelectedObject;
            buttonnumber = ismember(app.ShowlinesButtonGroup.Buttons,selectedButton);
            rBIndex=find(buttonnumber==1);
            switch rBIndex
                case 2
                    if isempty(app.segmentClosenessForbiddenMatrix)==0
                        for i=1:size(app.gyms,1)
                            for j=1:size(app.shops,1)
                                if app.segmentClosenessForbiddenMatrix(i,j)==0
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','b','LineStyle','--','LineWidth',2);
                                    set(l_obj,'UserData','forbiddenMatrix');
                                else
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','r','LineStyle','--');
                                    set(l_obj,'UserData','forbiddenMatrix');
                                end
                            end
                        end
                    end
                case 3
                    if isempty(app.coreForbiddenMatrix)==0
                        for i=1:size(app.gyms,1)
                            for j=1:size(app.shops,1)
                                if app.coreForbiddenMatrix(i,j)==0
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','b','LineStyle','--','LineWidth',2);
                                    set(l_obj,'UserData','forbiddenMatrix');
                                else
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','r','LineStyle','--');
                                    set(l_obj,'UserData','forbiddenMatrix');
                                end
                            end
                        end
                    end
                case 4
                    if isempty(app.coreCoreMustMatrix)==0
                        for i=1:size(app.gyms,1)
                            for j=1:size(app.shops,1)
                                if app.coreCoreMustMatrix(i,j)==0
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','b','LineStyle','--','LineWidth',2);
                                    set(l_obj,'UserData','forbiddenMatrix');
                                else
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','r','LineStyle','--');
                                    set(l_obj,'UserData','forbiddenMatrix');
                                end
                            end
                        end
                    end
                case 5
                    if isempty(app.shadowsForbiddensMatrix)==0
                        for i=1:size(app.gyms,1)
                            for j=1:size(app.shops,1)
                                if app.shadowsForbiddensMatrix(i,j)==0
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','b','LineStyle','--','LineWidth',2);
                                    set(l_obj,'UserData','forbiddenMatrix');
                                else
                                    l_obj=line(app.UIAxes,[app.gyms(i,1),app.shops(j,1)],[app.gyms(i,2),app.shops(j,2)],'color','r','LineStyle','--');
                                    set(l_obj,'UserData','forbiddenMatrix');
                                end
                            end
                        end
                    end
            end
        end

        function drawDroplets(app, x_in)
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Tag','teardrop');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end
            if app.isShowTeardrop==1
                %             totalBreak=false;%FOR DEBUGGING
                eachArcStep=0.05;
                %xs=[];
                %ys=[];
                            counter=0;
                for i=1:size(x_in,1)
                    for j=1:size(x_in,2)
                        if x_in(i,j)==1
                            %\/\/\/ Draw gym tear drops
                            xcS = app.shops(i,1);
                            ycS = app.shops(i,2);

                            xcG = app.gyms(j,1);
                            ycG = app.gyms(j,2);

                            u=[1,0];
                            v=[xcS-xcG,ycS-ycG];
                            u = u/norm(u);
                            v = v/norm(v);
                            angleGS=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;

                            if(v(1,2)<0)
                                angleGS=180-angleGS;
                                startAngleGS=angleGS+120;
                                endAngleGS=angleGS-120;
                            else
                                startAngleGS=angleGS-60;
                                endAngleGS=angleGS+60;
                            end

                            if(startAngleGS<0)
                                startAngleGS=360+startAngleGS;
                            end
                            if(endAngleGS<0)
                                endAngleGS=360+endAngleGS;
                            end
                            if(startAngleGS>360)
                                startAngleGS=startAngleGS-360;
                            end
                            if(endAngleGS>360)
                                endAngleGS=endAngleGS-360;
                            end

                            if endAngleGS<180 && startAngleGS>180
                                temp=startAngleGS;
                                startAngleGS=endAngleGS;
                                endAngleGS=temp;
                                theta = (startAngleGS)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                            elseif endAngleGS<180 && startAngleGS>0
                                theta1 = (0)*pi/180:eachArcStep:(startAngleGS)*pi/180;
                                theta2 = (endAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta=[theta2,theta1];
                            elseif endAngleGS>180 && startAngleGS<180
                                theta1 = (0)*pi/180:eachArcStep:(startAngleGS)*pi/180;
                                theta2 = (endAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta=[theta2,theta1];
                            elseif endAngleGS>180 && startAngleGS>270
                                theta1 = (startAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta2 = (0)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                                theta=[theta2,theta1];
                            elseif endAngleGS>180 && startAngleGS>180
                                theta1 = (startAngleGS)*pi/180:-eachArcStep:(0)*pi/180;
                                theta2 = (360)*pi/180:-eachArcStep:(endAngleGS)*pi/180;
                                theta=[theta1,theta2];
                            end
                            r=manualPdist(app,[app.shops(i,:);app.gyms(j,:)])/2;


                            x = xcG + r*cos(theta);
                            y = ycG + r*sin(theta);

                            xs=[];
                            ys=[];

                            xs=[x,xcS];
                            ys=[y,ycS];

                            P = polyshape(xs,ys);
                            color=[0.0,0.0,0.8];
                            plot(app.UIAxes,P,'EdgeAlpha',0.4,'EdgeColor',[1,0,0],'FaceColor',color,'FaceAlpha',0.2,'Tag','teardrop')
                            %^^^ Draw gym tear drops

                            %\/\/\/ Draw shop tear drops
                            xcS = app.shops(i,1);
                            ycS = app.shops(i,2);

                            xcG = app.gyms(j,1);
                            ycG = app.gyms(j,2);

                            u=[1,0];
                            v=[xcS-xcG,ycS-ycG];
                            u = u/norm(u);
                            v = v/norm(v);
                            angleGS=atan2(norm(cross([u,0],[v,0])), dot([u,0],[v,0]))*180/pi;

                            if(v(1,2)<0)
                                angleGS=180-angleGS;
                                startAngleGS=angleGS+60;
                                endAngleGS=angleGS-60;
                            else
                                startAngleGS=angleGS-120;
                                endAngleGS=angleGS+120;
                            end

                            if(startAngleGS<0)
                                startAngleGS=360+startAngleGS;
                            end
                            if(endAngleGS<0)
                                endAngleGS=360+endAngleGS;
                            end
                            if(startAngleGS>360)
                                startAngleGS=startAngleGS-360;
                            end
                            if(endAngleGS>360)
                                endAngleGS=endAngleGS-360;
                            end
                            

                            if endAngleGS<180 && startAngleGS>180
                                theta1 = (0)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                                theta2 = (startAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta=[theta2,theta1];
                            elseif endAngleGS<180 && startAngleGS<180
                                theta1 = (endAngleGS)*pi/180:-eachArcStep:(0)*pi/180;
                                theta2 = (360)*pi/180:-eachArcStep:(startAngleGS)*pi/180;
                                theta=[theta1,theta2];
                            elseif endAngleGS>180 && startAngleGS<180
                                theta = (startAngleGS)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                            elseif endAngleGS>180 && startAngleGS>270
                                theta1 = (startAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta2 = (0)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                                theta=[theta1,theta2];
                            elseif endAngleGS>180 && startAngleGS>180
                                theta1 = (startAngleGS)*pi/180:eachArcStep:(360)*pi/180;
                                theta2 = (0)*pi/180:eachArcStep:(endAngleGS)*pi/180;
                                theta=[theta1,theta2];
                                %elseif

                            end
                            r=manualPdist(app,[app.shops(i,:);app.gyms(j,:)])/2;


                            x = xcS + r*cos(theta);
                            y = ycS + r*sin(theta);

                            xs=[];
                            ys=[];

                            xs=[x,xcG];
                            ys=[y,ycG];

                            P = polyshape(xs,ys);
                            color=[0.0,0.8,0.0];
                            plot(app.UIAxes,P,'EdgeAlpha',0.4,'EdgeColor',[1,0,0],'FaceColor',color,'FaceAlpha',0.2,'Tag','teardrop')
                            %^^^ Draw shop tear drops

                            %theta = (0)*pi/180:eachArcStep:(360)*pi/180;

                            %x = xcG + r*cos(theta);
                            %y = ycG + r*sin(theta);

                            %P = polyshape(x,y);
                            %color=[0.8,0.0,0.0];
                            %plot(app.UIAxes,P,'EdgeAlpha',0,'FaceColor',color,'FaceAlpha',0.1)

                            %disp('!')

                            %viscircles(app.UIAxes,app.shops(i,:),r,'color','g');
                            %viscircles(app.UIAxes,app.shops(j,:),r,'color','b');
                            %totalBreak=true;
                        end
                    end
                end
            end
        end
        
        function shadowForbiddens = checkShadow(app)
%             app.isShadowActive=true;% temp

            shadowForbiddens(size(app.shops,1),size(app.gyms,1))=0;
            [SVCells,GVCells]=calcCores(app,app.shops,app.gyms);%DONE
            [Sxs,Sys]=getPolygon(app,SVCells);
            [Gxs,Gys]=getPolygon(app,GVCells);

            for m=1:size(app.gyms,1)
                for n=1:size(app.shops,1)
                    for k=1:size(app.shops,1)
                        if n~=k
                            tangentPoints=getTangent(app,Sxs{1,n},Sys{1,n},app.gyms(m,:));

%                             line(app.UIAxes,[app.gyms(1,1),tangentPoints(1,1)],[app.gyms(1,2),tangentPoints(1,2)],'color','b','LineWidth',6,'LineStyle','-');

                            A=[app.gyms(m,1),app.gyms(m,2)];
                            B=[tangentPoints(1,1),tangentPoints(1,2)];

                            t=-2;

                            newP1=A*t+B*(1-t);
                            if app.isShadowActive==true
                                line(app.UIAxes,[newP1(1,1) tangentPoints(1,1)],[newP1(1,2) tangentPoints(1,2)],'Color','blue')
                            end
                            A=[app.gyms(m,1),app.gyms(m,2)];
                            B=[tangentPoints(2,1),tangentPoints(2,2)];

                            t=-2;

                            newP2=A*t+B*(1-t);

                            if app.isShadowActive==true
                                line(app.UIAxes,[newP2(1,1) tangentPoints(2,1)],[newP2(1,2) tangentPoints(2,2)],'Color','blue')
                                line(app.UIAxes,[tangentPoints(1,1) tangentPoints(2,1)],[tangentPoints(1,2) tangentPoints(2,2)],'Color','blue')
                            end

                            testPoint=app.shops(k,:);
                            if app.isShadowActive==true
%                                 scatter(app.UIAxes,testPoint(1,1),testPoint(1,2))
% 
%                                 scatter(app.UIAxes,newP1(1,1),newP1(1,2),'+')
% 
%                                 scatter(app.UIAxes,newP2(1,1),newP2(1,2),'x')
% 
%                                 scatter(app.UIAxes,tangentPoints(1,1),tangentPoints(1,2),'d')
% 
%                                 scatter(app.UIAxes,tangentPoints(2,1),tangentPoints(2,2),'^')
                            end
                            c1=cross([newP1-tangentPoints(1,:),0],[tangentPoints(1,:)-testPoint,0]);
                            c2=cross([tangentPoints(1,:)-tangentPoints(2,:),0],[tangentPoints(2,:)-testPoint,0]);
                            c3=cross([tangentPoints(2,:)-newP2,0],[newP2-testPoint,0]);

                            if (c1(1,3)<0 && c2(1,3)<0 && c3(1,3)<0)
                                disp(['gym ',num2str(m), 'can not connect to shop ',num2str(k)])
                                shadowForbiddens(m,k)=1;
                            else
                                disp(['gym ',num2str(m), 'can connect to shop ',num2str(k)])
                            end
                        end
                    end
                end
            end

            for m=1:size(app.shops,1)
                for n=1:size(app.gyms,1)
                    for k=1:size(app.gyms,1)
                        if n~=k
                            tangentPoints=getTangent(app,Gxs{1,n},Gys{1,n},app.shops(m,:));

%                             line(app.UIAxes,[app.gyms(1,1),tangentPoints(1,1)],[app.gyms(1,2),tangentPoints(1,2)],'color','b','LineWidth',6,'LineStyle','-');

                            A=[app.shops(m,1),app.shops(m,2)];
                            B=[tangentPoints(1,1),tangentPoints(1,2)];

                            t=-2;

                            newP1=A*t+B*(1-t);
                            if app.isShadowActive==true
                                line(app.UIAxes,[newP1(1,1) tangentPoints(1,1)],[newP1(1,2) tangentPoints(1,2)],'Color','green')
                            end
                            A=[app.shops(m,1),app.shops(m,2)];
                            B=[tangentPoints(2,1),tangentPoints(2,2)];

                            t=-2;

                            newP2=A*t+B*(1-t);
                            if app.isShadowActive==true
                                line(app.UIAxes,[newP2(1,1) tangentPoints(2,1)],[newP2(1,2) tangentPoints(2,2)],'Color','green')
                                line(app.UIAxes,[tangentPoints(1,1) tangentPoints(2,1)],[tangentPoints(1,2) tangentPoints(2,2)],'Color','green')
                            end
                            testPoint=app.gyms(k,:);
                            if app.isShadowActive==true
%                                 scatter(app.UIAxes,testPoint(1,1),testPoint(1,2))
% 
%                                 scatter(app.UIAxes,newP1(1,1),newP1(1,2),'+')
% 
%                                 scatter(app.UIAxes,newP2(1,1),newP2(1,2),'x')
% 
%                                 scatter(app.UIAxes,tangentPoints(1,1),tangentPoints(1,2),'d')
% 
%                                 scatter(app.UIAxes,tangentPoints(2,1),tangentPoints(2,2),'^')
                            end
                            c1=cross([newP1-tangentPoints(1,:),0],[tangentPoints(1,:)-testPoint,0]);
                            c2=cross([tangentPoints(1,:)-tangentPoints(2,:),0],[tangentPoints(2,:)-testPoint,0]);
                            c3=cross([tangentPoints(2,:)-newP2,0],[newP2-testPoint,0]);

                            if (c1(1,3)<0 && c2(1,3)<0 && c3(1,3)<0)
                                disp(['shop ',num2str(m), 'can not connect to gym ',num2str(k)])
                                shadowForbiddens(m,k)=1;
                            else
                                disp(['shop ',num2str(m), 'can connect to gym ',num2str(k)])
                            end
                        end
                    end
                end
            end
 
            disp('!!!')

        end
        
        function [xss,yss] = getPolygon(app,VCells)
            xss=cell(1,size(VCells,1)-1);
            yss=cell(1,size(VCells,1)-1);
            eachArcStep=0.01;
            for cir=2:size(VCells,1)
                xs=[];
                ys=[];
                %isNeedsDraw=false;
                for i=1:size(VCells{cir,5},2)
                    NI=[];
                    %isNeedsDraw=false;
                    for j=1:size(VCells{cir,2},2)
                        if VCells{cir,5}(1,i)==VCells{cir,2}(1,j)
                            NI = j;
                            break;
                        end
                    end
                    if isempty(NI)==false
                        xc = VCells{cir,3}(1,NI);
                        yc = VCells{cir,3}(2,NI);
                        r = VCells{cir,4}(1,NI);

                        if size(VCells{cir,5},1)>2
                            if VCells{cir,5}(2,i)>VCells{cir,5}(3,i)
                                theta_p = (VCells{cir,5}(2,i))*pi/180:eachArcStep:(360)*pi/180;
                                theta_pp = (0)*pi/180:eachArcStep:(VCells{cir,5}(3,i))*pi/180;
                                theta=[theta_p,theta_pp];
                            else
                                theta = (VCells{cir,5}(2,i))*pi/180:eachArcStep:(VCells{cir,5}(3,i))*pi/180;
                            end
                        else
                            theta = (0)*pi/180:eachArcStep:(360)*pi/180;
                        end

                        x = xc + r*cos(theta);
                        y = yc + r*sin(theta);
                        xs=[xs,x];
                        ys=[ys,y];
                        %isNeedsDraw=true;
                    end
                end
                %if isNeedsDraw==true
                %    P = polyshape(xs,ys);
                %    plot(app.UIAxes,P,'FaceColor',color,'FaceAlpha',0.6)
                %end
                %disp('!!!')
                xss{cir-1}=xs;
                yss{cir-1}=ys;
            end
        end
        
        function tangentPoints = getTangent(app,xs,ys,p)
            tangentPoints(2,2)=0;
            for i=1:size(xs,2)
                isAllPositive=true;
                isAllNegative=true;
                px=[p(1,1)-xs(1,i),p(1,2)-ys(1,i),0];
                for j=1:size(xs,2)
                    if i~=j
                        xx=[xs(1,j)-xs(1,i),ys(1,j)-ys(1,i),0];
                        res=cross(px,xx);
                        if res(1,3)>0
                            isAllNegative=false;
                        end
                        if res(1,3)<0
                            isAllPositive=false;
                        end
                    end
                end
                if isAllNegative
                    tangentPoints(1,:)=[xs(1,i),ys(1,i)];
                end
                if isAllPositive
                    tangentPoints(2,:)=[xs(1,i),ys(1,i)];
                end
            end
        end

        function results = drawPenalties(app)
            Ap=app.shops;
            Bp=app.gyms;
            
            permut_Min_Sum_Euc=     Min_Sum_Euclidean_Matching( Ap(:,2),Ap(:,1),Bp(:,2), Bp(:,1) );
%             disp("computed Min Sum Euc \n\n")

            permut_Min_Sum_Pen=     Min_Sum_Penalty_Matching( Ap(:,2),Ap(:,1),Bp(:,2), Bp(:,1) );
%             disp("computed Min Sum Euc \n\n")


            permut_Min_BN_Euc= Min_BN_Euc( Ap(:,2),Ap(:,1),Bp(:,2), Bp(:,1) ); %Bottleneck
%             sprintf("computed Bottleneck Euc   \n\n")

            [permut_Min_BN_Pen, MaxPenV]= Min_BN_Pen( Ap(:,2),Ap(:,1),Bp(:,2), Bp(:,1) );
%             sprintf("computed Bottleneck Pen   \n\n",'color','red')


            permut_Min_BN_Sum_Pen = Min_BN_Sum_Pen( Ap(:,2),Ap(:,1),Bp(:,2), Bp(:,1) );
%             sprintf("computed BN  Sum Pen Pen   \n\n",'color','red')
            %The weight of an edge is the sum of vertices's weights

            n=100;


            q_Min_Sum_Euc=      zeros(n,n,2) ;
            q_Min_BN_Pen=      zeros(n,n,2) ;
            q_Min_Sum_Pen=     zeros(n,n,2) ;
            q_Min_BN_Euc=         zeros(n,n,2) ;
            q_Min_BN_Param=         zeros(n,n,2) ;

%             sprintf("computed Bottleneck Penalties   \n\n")

            q_Added_Pen  =        zeros(n,n,2) ;

            for i=1:n
                for j=1:n
                    % dd=zeros(nPnts,2);
                    x=j/n ; y=i/n;

                    dA=  ((x-Ap(:,2)).^2          + (y-Ap(:,1)).^2 ).^0.5  ;

                    dB=  ((x-Bp(:,2)).^2 + (y-Bp(:,1)).^2 ).^0.5;
                    [mdA imdA]=min(dA) ; [mdB imdB] = min(dB);
                    [u v]=min( max(    [dA /mdA, dB/mdB ] , [], 2)) ;
                    q0(i,j,[1 2])=[u v];

                    dB=  ((x-Bp(permut_Min_Sum_Euc(:),2)).^2 + (y-Bp(permut_Min_Sum_Euc(:),1)).^2 ).^0.5;
                    mdA=min(dA) ; mdB = min(dB);
                    [u v]=min( max(    [dA /mdA, dB/mdB ] , [], 2)) ;
                    q_Min_Sum_Euc(i,j,[1 2])=[u v];

                    dB=  ((x-Bp(permut_Min_Sum_Pen(:),2)).^2 + (y-Bp(permut_Min_Sum_Pen(:),1)).^2 ).^0.5;
                    [u v]=min( max(    [dA/mdA , dB/mdB ] , [], 2)) ;
                    q_Min_Sum_Pen(i,j,[1 2])=[u v];

                    dB=  ((x-Bp(permut_Min_BN_Sum_Pen(:),2)).^2 + (y-Bp( permut_Min_BN_Sum_Pen(:),1)).^2 ).^0.5;
                    [u v]=min((dA+dB)/(mdA+mdB));  %Q ADDED _ NOTE THE DIFFERENCE
                    q_Added_Pen(i,j,[1 2])=[u v];

                    dB=  ((x-Bp(permut_Min_BN_Pen(:),2)).^2 + (y-Bp(permut_Min_BN_Pen(:),1)).^2 ).^0.5;
                    [u v]=min( max(    [dA/mdA , dB/mdB ] , [], 2)) ;
                    q_Min_BN_Pen(i,j,[1 2])=[u v];

%                     dB=  ((x-Bp(permut_Min_BN_Pen_Param(:),2)).^2 + (y-Bp(permut_Min_BN_Pen_Param(:),1)).^2 ).^0.5;
%                     [u v]=min( max(    [dA/mdA , dB/mdB ] , [], 2)) ;
%                     q_Min_BN_Pen_Param(i,j,[1 2])=[u v];
                end
            end
            imagesc(app.UIAxes, [0 1],[0 1] ,q_Min_Sum_Euc(:,:,1)');
            [val,I]=max(q_Min_Sum_Euc(:,:,1),[],'all');
            Ix=floor(I/n);
            Iy=mod(I,n);
            scatter(app.UIAxes,Iy/n,Ix/n,'p');
            text(app.UIAxes,Iy/n,Ix/n,num2str(val))
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("MeasuresAndUtils\")
            currectDir = mfilename('fullpath');
            lastdot_pos = find(currectDir == filesep, 1, 'last');
            currectDir=extractBetween(currectDir,1,lastdot_pos);
            cd(currectDir{1,1})
            rng(8);
            app.isOnLine=false;
            app.numShops=6;
            app.numGyms=6;
            app.isDrawForbiddens=false;
            app.isDrawCores=true;
            app.isShowPenalties=false;
            app.shops=rand(app.numShops,2);
            app.gyms=rand(app.numGyms,2);
            if app.isOnLine==true
                for i=1:app.numShops
                    app.shops(i,2)=rand(1,1)*0.001;
                end
                for i=1:app.numGyms
                    app.gyms(i,2)=rand(1,1)*0.001;
                end
            end
            app.shopsPOIs=cell(1,app.numShops);
            app.gymsPOIs=cell(1,app.numShops);
            for i=1:app.numShops
                app.shopsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',app.shops(i,:),'Color','b');
                addlistener(app.shopsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            for i=1:app.numGyms
                app.gymsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',app.gyms(i,:),'Color','g');
                addlistener(app.gymsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            run(app,app.shops,app.gyms);
        end

        % Button pushed function: QuicksaveButton
        function QuicksaveButtonPushed(app, event)
            title(app.UIAxes,'SAVING ...')
            n=size(app.shopsPOIs,2);
            shopPositions(n,2)=0;
            for i=1:n
                shopPositions(i,:)=app.shopsPOIs{1,i}.Position;
            end
            n=size(app.gymsPOIs,2);
            gymPositions(n,2)=0;
            for i=1:n
                gymPositions(i,:)=app.gymsPOIs{1,i}.Position;
            end
            save('inputShopGym.mat','shopPositions','gymPositions')
            %app.isChangedForSave=false;
            title(app.UIAxes,'')
        end

        % Button pushed function: QuickloadButton
        function QuickloadButtonPushed(app, event)
            title(app.UIAxes,'LOADING ...')
            if isfile('inputShopGym.mat')
                load inputShopGym.mat
                app.shops=shopPositions;
                app.gyms=gymPositions;
                app.numShops=size(app.shops,1);
                app.numGyms=size(app.gyms,1);
                run(app,app.shops,app.gyms);
                title(app.UIAxes,'')
            else
                title(app.UIAxes,'NO QUICK SAVE AVILABLE')
            end

        end

        % Value changed function: SegmentclosenesslogicButton
        function SegmentclosenesslogicButtonValueChanged(app, event)
            value = app.SegmentclosenesslogicButton.Value;
            if value==1
                app.isSegmentClosenessLogicActive=true;
            else
                app.isSegmentClosenessLogicActive=false;
            end
            run(app,app.shops,app.gyms);
        end

        % Value changed function: CoreforbiddenintersectionlogicButton
        function CoreforbiddenintersectionlogicButtonValueChanged(app, event)
            value = app.CoreforbiddenintersectionlogicButton.Value;
            if value==1
                app.isCoreForbiddenActive=true;
            else
                app.isCoreForbiddenActive=false;
            end
            run(app,app.shops,app.gyms);
        end

        % Value changed function: CorecoremustlogicButton
        function CorecoremustlogicButtonValueChanged(app, event)
            value = app.CorecoremustlogicButton.Value;
            if value==1
                app.isCoreCoreMustActive=true;
            else
                app.isCoreCoreMustActive=false;
            end
            run(app,app.shops,app.gyms);
        end

        % Value changed function: 
        % ThreeormorecoreintersectionhaltlogicButton
        function ThreeormorecoreintersectionhaltlogicButtonValueChanged(app, event)
            value = app.ThreeormorecoreintersectionhaltlogicButton.Value;
            if value==1
                app.isThreeCoreHaltActive=true;
            else
                app.isThreeCoreHaltActive=false;
            end
            run(app,app.shops,app.gyms);
        end

        % Selection changed function: ShowlinesButtonGroup
        function ShowlinesButtonGroupSelectionChanged(app, event)
            drawMatrixLines(app);
        end

        % Value changed function: ShowVoronoiCheckBox
        function ShowVoronoiCheckBoxValueChanged(app, event)
            value = app.ShowVoronoiCheckBox.Value;
            if value==1
                app.isShowVoronoi=1;
            else
                app.isShowVoronoi=0;
            end
            drawMatrixLines(app);
        end

        % Value changed function: ShowteardropsCheckBox
        function ShowteardropsCheckBoxValueChanged(app, event)
            value = app.ShowteardropsCheckBox.Value;
            if value==1
                app.isShowTeardrop=1;
            else
                app.isShowTeardrop=0;
            end
            x=decodeSolution(app,app.lastSolution,app.numShops,app.numGyms,true);
            drawDroplets(app,x);
        end

        % Value changed function: AlwaysresetviewCheckBox
        function AlwaysresetviewCheckBoxValueChanged(app, event)
            value = app.AlwaysresetviewCheckBox.Value;
            if value==1
                app.isAlwaysResetView=1;
            else
                app.isAlwaysResetView=0;
            end
        end

        % Value changed function: NumshopsgymsEditField
        function NumshopsgymsEditFieldValueChanged(app, event)
            value = app.NumshopsgymsEditField.Value;
            app.numShops=value;
            app.numGyms=value;
            app.shops=rand(app.numShops,2);
            app.gyms=rand(app.numGyms,2);
            if app.isOnLine==true
                for i=1:app.numShops
                    app.shops(i,2)=rand(1,1)*0.001;
                end
                for i=1:app.numGyms
                    app.gyms(i,2)=rand(1,1)*0.001;
                end
            end
            app.shopsPOIs=cell(1,app.numShops);
            app.gymsPOIs=cell(1,app.numShops);
            for i=1:app.numShops
                app.shopsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',app.shops(i,:),'Color','b');
                addlistener(app.shopsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            for i=1:app.numGyms
                app.gymsPOIs{1,i} = images.roi.Point(app.UIAxes,'Position',app.gyms(i,:),'Color','g');
                addlistener(app.gymsPOIs{1,i},'ROIMoved',@app.ROIEvent);
            end
            run(app,app.shops,app.gyms);
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            run(app,app.shops,app.gyms);
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            title(app.UIAxes,'SAVING ...')
            n=size(app.shopsPOIs,2);
            shopPositions(n,2)=0;
            for i=1:n
                shopPositions(i,:)=app.shopsPOIs{1,i}.Position;
            end
            n=size(app.gymsPOIs,2);
            gymPositions(n,2)=0;
            for i=1:n
                gymPositions(i,:)=app.gymsPOIs{1,i}.Position;
            end
            save(strcat(path,file),'shopPositions','gymPositions')
            %app.isChangedForSave=false;
            title(app.UIAxes,'')
        end

        % Button pushed function: LoadasButton
        function LoadasButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            title(app.UIAxes,'LOADING ...')
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(strcat(path,file),'shopPositions','gymPositions')
                app.shops=shopPositions;
                app.gyms=gymPositions;
                app.numShops=size(app.shops,1);
                app.numGyms=size(app.gyms,1);
                run(app,app.shops,app.gyms);
                title(app.UIAxes,'')
            end
        end

        % Value changed function: ShadowlogicButton
        function ShadowlogicButtonValueChanged(app, event)
            %checkShadow(app);
            value = app.ShadowlogicButton.Value;
            if value==1
                app.isShadowActive=1;
            else
                app.isShadowActive=0;
            end
            run(app,app.shops,app.gyms);
        end

        % Value changed function: ShowpenaltiesButton
        function ShowpenaltiesButtonValueChanged(app, event)
            value = app.ShowpenaltiesButton.Value;
            if value==1
                app.isShowPenalties=1;
            else
                app.isShowPenalties=0;
            end
            run(app,app.shops,app.gyms);
        end

        % Value changed function: ShowforbiddenregionsCheckBox
        function ShowforbiddenregionsCheckBoxValueChanged(app, event)
            value = app.ShowforbiddenregionsCheckBox.Value;
            if value==1
                app.isDrawForbiddens=1;
                drawForbiddenAreas(app,app.shops,app.gyms);
            else
                app.isDrawForbiddens=0;
                run(app,app.shops,app.gyms);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsPanel);
            app.QuicksaveButton = uibutton(app.GridLayout2, 'push');
            app.QuicksaveButton.ButtonPushedFcn = createCallbackFcn(app, @QuicksaveButtonPushed, true);
            app.QuicksaveButton.Text = 'Quick save';
            app.SaveasButton = uibutton(app.GridLayout2, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.QuickloadButton = uibutton(app.GridLayout2, 'push');
            app.QuickloadButton.ButtonPushedFcn = createCallbackFcn(app, @QuickloadButtonPushed, true);
            app.QuickloadButton.Text = 'Quick load';
            app.LoadasButton = uibutton(app.GridLayout2, 'push');
            app.LoadasButton.ButtonPushedFcn = createCallbackFcn(app, @LoadasButtonPushed, true);
            app.LoadasButton.Text = 'Load as';
            app.SegmentclosenesslogicButton = uibutton(app.GridLayout2, 'state');
            app.SegmentclosenesslogicButton.ValueChangedFcn = createCallbackFcn(app, @SegmentclosenesslogicButtonValueChanged, true);
            app.SegmentclosenesslogicButton.Text = 'Segment closeness logic';
            app.CoreforbiddenintersectionlogicButton = uibutton(app.GridLayout2, 'state');
            app.CoreforbiddenintersectionlogicButton.ValueChangedFcn = createCallbackFcn(app, @CoreforbiddenintersectionlogicButtonValueChanged, true);
            app.CoreforbiddenintersectionlogicButton.Text = 'Core forbidden intersection logic';
            app.CorecoremustlogicButton = uibutton(app.GridLayout2, 'state');
            app.CorecoremustlogicButton.ValueChangedFcn = createCallbackFcn(app, @CorecoremustlogicButtonValueChanged, true);
            app.CorecoremustlogicButton.Text = 'Core core must logic';
            app.ThreeormorecoreintersectionhaltlogicButton = uibutton(app.GridLayout2, 'state');
            app.ThreeormorecoreintersectionhaltlogicButton.ValueChangedFcn = createCallbackFcn(app, @ThreeormorecoreintersectionhaltlogicButtonValueChanged, true);
            app.ThreeormorecoreintersectionhaltlogicButton.Text = 'Three or more core intersection halt logic';
            app.ShadowlogicButton = uibutton(app.GridLayout2, 'state');
            app.ShadowlogicButton.ValueChangedFcn = createCallbackFcn(app, @ShadowlogicButtonValueChanged, true);
            app.ShadowlogicButton.Text = 'Shadow logic';
            app.ShowpenaltiesButton = uibutton(app.GridLayout2, 'state');
            app.ShowpenaltiesButton.ValueChangedFcn = createCallbackFcn(app, @ShowpenaltiesButtonValueChanged, true);
            app.ShowpenaltiesButton.Text = 'Show penalties';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.ShowlinesButtonGroup = uibuttongroup(app.GridLayout3);
            app.ShowlinesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowlinesButtonGroupSelectionChanged, true);
            app.ShowlinesButtonGroup.Title = 'Show lines';
            app.ShownolineButton = uiradiobutton(app.ShowlinesButtonGroup);
            app.ShownolineButton.Text = 'Show no line';
            app.ShownolineButton.Value = true;
            app.SegmentclosenessButton = uiradiobutton(app.ShowlinesButtonGroup);
            app.SegmentclosenessButton.Text = 'Segment closeness';
            app.CoreforbiddenButton = uiradiobutton(app.ShowlinesButtonGroup);
            app.CoreforbiddenButton.Text = 'Core forbidden';
            app.CorecoreButton = uiradiobutton(app.ShowlinesButtonGroup);
            app.CorecoreButton.Text = 'Core core';
            app.ShadowButton = uiradiobutton(app.ShowlinesButtonGroup);
            app.ShadowButton.Text = 'Shadow';
            app.OptionsPanel = uipanel(app.GridLayout3);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout4 = uigridlayout(app.OptionsPanel);
            app.ShowVoronoiCheckBox = uicheckbox(app.GridLayout4);
            app.ShowVoronoiCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowVoronoiCheckBoxValueChanged, true);
            app.ShowVoronoiCheckBox.Text = 'Show Voronoi';
            app.ShowteardropsCheckBox = uicheckbox(app.GridLayout4);
            app.ShowteardropsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowteardropsCheckBoxValueChanged, true);
            app.ShowteardropsCheckBox.Text = 'Show teardrops';
            app.AlwaysresetviewCheckBox = uicheckbox(app.GridLayout4);
            app.AlwaysresetviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlwaysresetviewCheckBoxValueChanged, true);
            app.AlwaysresetviewCheckBox.Text = 'Always reset view';
            app.Panel_2 = uipanel(app.GridLayout4);
            app.NumshopsgymsEditFieldLabel = uilabel(app.Panel_2);
            app.NumshopsgymsEditFieldLabel.Text = 'Num shops/gyms';
            app.NumshopsgymsEditField = uieditfield(app.Panel_2, 'numeric');
            app.NumshopsgymsEditField.ValueChangedFcn = createCallbackFcn(app, @NumshopsgymsEditFieldValueChanged, true);
            app.NumshopsgymsEditField.Value = 6;
            app.RedrawButton = uibutton(app.GridLayout4, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.ShowforbiddenregionsCheckBox = uicheckbox(app.GridLayout4);
            app.ShowforbiddenregionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowforbiddenregionsCheckBoxValueChanged, true);
            app.ShowforbiddenregionsCheckBox.Text = 'Show forbidden regions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoreILP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end